classdef DataAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ControlTab                     matlab.ui.container.Tab
        PAUSEButton                    matlab.ui.control.StateButton
        STOPButton                     matlab.ui.control.StateButton
        StatusLamp                     matlab.ui.control.Lamp
        StatusLampLabel                matlab.ui.control.Label
        LowerCCDLamp                   matlab.ui.control.Lamp
        LowerCCDLampLabel              matlab.ui.control.Label
        UpperCCDLamp                   matlab.ui.control.Lamp
        UpperCCDLampLabel              matlab.ui.control.Label
        CCDSettingsPanel               matlab.ui.container.Panel
        ShutdownButton                 matlab.ui.control.Button
        CurrentCCDButtonGroup          matlab.ui.container.ButtonGroup
        LowerCCDButton                 matlab.ui.control.RadioButton
        UpperCCDButton                 matlab.ui.control.RadioButton
        ExposuremsSpinner              matlab.ui.control.Spinner
        ExposuremsSpinnerLabel         matlab.ui.control.Label
        InitializeButton               matlab.ui.control.Button
        EXPORTDATAButton               matlab.ui.control.Button
        DataprocessingparametersPanel  matlab.ui.container.Panel
        PostprocessingscriptsTextArea  matlab.ui.control.TextArea
        PostprocessingscriptsTextAreaLabel  matlab.ui.control.Label
        TotalElapsedTimeLabel          matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ElapsedTimeLabel               matlab.ui.control.Label
        StatusOutputTextArea           matlab.ui.control.TextArea
        StatusOutputTextAreaLabel      matlab.ui.control.Label
        PathLabel                      matlab.ui.control.Label
        BrowseFileButton               matlab.ui.control.Button
        File0TextArea                  matlab.ui.control.TextArea
        File0TextAreaLabel             matlab.ui.control.Label
        DataModeButtonGroup            matlab.ui.container.ButtonGroup
        DMDTestButton                  matlab.ui.control.RadioButton
        Live1CroppedButton             matlab.ui.control.RadioButton
        Live8Button                    matlab.ui.control.RadioButton
        Live4Button                    matlab.ui.control.RadioButton
        Live2Button                    matlab.ui.control.RadioButton
        Live1Button                    matlab.ui.control.RadioButton
        DataButton                     matlab.ui.control.RadioButton
        LiveanalysisparametersPanel    matlab.ui.container.Panel
        UpdateFiguresSwitch            matlab.ui.control.Switch
        UpdateFiguresSwitchLabel       matlab.ui.control.Label
        ThresholdTextArea              matlab.ui.control.TextArea
        ThresholdTextAreaLabel         matlab.ui.control.Label
        CenterYSpinner                 matlab.ui.control.Spinner
        CenterYSpinnerLabel            matlab.ui.control.Label
        RadiusYSpinner                 matlab.ui.control.Spinner
        RadiusYSpinnerLabel            matlab.ui.control.Label
        RadiusXSpinner                 matlab.ui.control.Spinner
        RadiusXSpinnerLabel            matlab.ui.control.Label
        CenterXSpinner                 matlab.ui.control.Spinner
        CenterXSpinnerLabel            matlab.ui.control.Label
        CalibrationDropDown            matlab.ui.control.DropDown
        CalibrationDropDownLabel       matlab.ui.control.Label
        AutoThresholdSwitch            matlab.ui.control.Switch
        AutoThresholdSwitchLabel       matlab.ui.control.Label
        OffsetCleanSwitch              matlab.ui.control.Switch
        OffsetCleanSwitchLabel         matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        BackgroundDropDownLabel        matlab.ui.control.Label
        NumberofimagesSpinner          matlab.ui.control.Spinner
        NumberofimagesSpinnerLabel     matlab.ui.control.Label
        RUNButton                      matlab.ui.control.Button
        FiguresTab                     matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes12                       matlab.ui.control.UIAxes
        UIAxes11                       matlab.ui.control.UIAxes
        UIAxes10                       matlab.ui.control.UIAxes
        UIAxes9                        matlab.ui.control.UIAxes
        UIAxes8                        matlab.ui.control.UIAxes
        UIAxes7                        matlab.ui.control.UIAxes
        UIAxes6                        matlab.ui.control.UIAxes
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        ImageMenu                      matlab.ui.container.Menu
        Image1Menu                     matlab.ui.container.Menu
        Image2Menu                     matlab.ui.container.Menu
        Image3Menu                     matlab.ui.container.Menu
        Image4Menu                     matlab.ui.container.Menu
        Image5Menu                     matlab.ui.container.Menu
        Image6Menu                     matlab.ui.container.Menu
        Image7Menu                     matlab.ui.container.Menu
        Image8Menu                     matlab.ui.container.Menu
        HistogramMenu                  matlab.ui.container.Menu
        Histogram1Menu                 matlab.ui.container.Menu
        Histogram2Menu                 matlab.ui.container.Menu
        Histogram3Menu                 matlab.ui.container.Menu
        Histogram4Menu                 matlab.ui.container.Menu
        Histogram5Menu                 matlab.ui.container.Menu
        Histogram6Menu                 matlab.ui.container.Menu
        Histogram7Menu                 matlab.ui.container.Menu
        Histogram8Menu                 matlab.ui.container.Menu
        AnalysisMenu                   matlab.ui.container.Menu
        ErrorandlossrateMenu           matlab.ui.container.Menu
        RetentionMenu                  matlab.ui.container.Menu
    end

       
    properties (Access = public)
        
        Mode = struct()

        Data = struct()
        Live = struct()
        Stat = struct()

        DataInfo = struct()
        FileInfo = struct()

    end
    
    properties (Constant, Access = private)

        PauseTime = 0.1

    end
    
    methods (Access = private)    

        function keepRunningUntilStop(app,MaxTime)
            
            for i = 1:MaxTime
                Timer = tic;

                % Check the status of interruption flag
                if app.STOPButton.Value
                    app.StatusLamp.Color = 'r';
                    app.STOPButton.Value = false;
                    break
                end  
                if app.PAUSEButton.Value
                    app.StatusLamp.Color = 'y';
                    while ~app.RESUMEButton.Value
                        pause(app.PauseTime)
                    end
                    app.PAUSEButton.Value = false;
                    app.RESUMEButton.Value = false;
                end
                app.StatusLamp.Color = 'g';
                app.LiveInfo.CurrentImg = i;

                % Get new image and background
                
                % Analyze new image
                
                % Update graphics
                
                if i>app.DataInfo.NumSave
%                     shiftVariable(app)
                end

                app.StatusLamp.Color = 'b';
                pause(app.PauseTime)
                app.ElapsedTimeLabel.Text = sprintf('Elapsed Time: %4.2f',toc(Timer));
                
            end
            app.StatusLamp.Color = 'r';

        end

        function Mode = gatherInput(app)           
            Mode.Dat = app.DataModeButtonGroup.SelectedObject.Text;

            Mode.Center = [app.CenterXSpinner.Value,app.CenterYSpinner.Value];
            Mode.Radius = [app.RadiusXSpinner.Value,app.RadiusYSpinner.Value];
            Mode.Threshold = str2num(app.ThresholdTextArea.Value{1});
            Mode.AutoThreshold = app.AutoThresholdSwitch.Value;
            Mode.OffsetClean = app.OffsetCleanSwitch.Value;
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UIStartUp(app)
            
            % Initialize and update Camera status
            NumCameras = AndorConfiguration;
            for i = 1:NumCameras
                [ret,CameraHandle] = GetCameraHandle(i-1);
                CheckWarning(ret)          
                [ret] = SetCurrentCamera(CameraHandle);
                CheckWarning(ret)    
                [ret, Number] = GetCameraSerialNumber();
                CheckWarning(ret)
                if Number == 19330
                    app.UpperCCDLamp.Color = 'g';
                end
                if Number == 19331
                    app.LowerCCDLamp.Color = 'g';
                end
            end
            
            % Update default data path
            app.PathLabel.Text = acquireDataPath;

            % Update background options
            app.BackgroundDropDown.Items = [{'Default','Mean','None'}, ...
                {dir('saved/MeanBg*.mat').name}];

            % Update calibration options
            app.CalibrationDropDown.Items = [{'Default','None'}, ...
                {dir('saved/Lat*.mat').name}];

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            

        end

        % Button pushed function: EXPORTDATAButton
        function EXPORTDATAButtonPushed(app, event)
            switch app.DataModeButtonGroup.SelectedObject.Text
                case {'Data','DMD Test'}
                    assignin("base","Stat",app.Stat)
                    assignin("base","Live",app.Live)
                case {'Live 1','Live 2','Live 4','Live 8','Live 1 Cropped'}
                    if app.Live.CurrentImg < app.DataInfo.NumSave
                        app.Data.Img = app.Data.Img(:,:,1:app.Live.CurrentImg);                
                        app.Data.Bg = app.Data.Bg(:,:,1:app.Live.CurrentImg);
                    end
                    assignin("base","Data",app.Data)
                    Data = app.Data; %#ok<NASGU,ADPROPLC>
                    uisave("Data",fullfile(app.PathLabel.Text,"Data.mat"))
            end
        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            app.FileInfo = initFileInfo;
            app.File0TextArea.Value = app.FileInfo.File;
            app.PathLabel.Text = app.FileInfo.Path;
            app.File0TextAreaLabel.Text = sprintf('File: %d',app.FileInfo.NumFile);
            app.UITable.Data = app.FileInfo.VarInfo.VarTable;
            app.UITable.ColumnName = app.FileInfo.VarInfo.Var;
            app.UITable.RowName = 'numbered';
            app.PostprocessingscriptsTextArea.Value = app.FileInfo.ScriptInfo.File;
            assignin("base","FileInfo",app.FileInfo)
            figure(app.UIFigure)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            assignin("base","Data",app.Data)
            assignin("base","DataInfo",app.DataInfo)
            assignin("base","FileInfo",app.FileInfo)
            assignin("base","Stat",app.Stat)
            assignin("base","Live",app.Live)
            delete(app)
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            AndorConfiguration;
        end

        % Button pushed function: ShutdownButton
        function ShutdownButtonPushed(app, event)
            AndorShuttingDown;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.RUNButton = uibutton(app.ControlTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.LiveanalysisparametersPanel = uipanel(app.ControlTab);
            app.LiveanalysisparametersPanel.Title = 'Live analysis parameters';
            app.NumberofimagesSpinnerLabel = uilabel(app.LiveanalysisparametersPanel);
            app.NumberofimagesSpinnerLabel.Text = 'Number of images';
            app.NumberofimagesSpinner = uispinner(app.LiveanalysisparametersPanel);
            app.NumberofimagesSpinner.Limits = [0 Inf];
            app.NumberofimagesSpinner.RoundFractionalValues = 'on';
            app.NumberofimagesSpinner.ValueDisplayFormat = '%d';
            app.NumberofimagesSpinner.Value = 40;
            app.BackgroundDropDownLabel = uilabel(app.LiveanalysisparametersPanel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.LiveanalysisparametersPanel);
            app.BackgroundDropDown.Items = {'Default', 'Current', 'None'};
            app.BackgroundDropDown.Value = 'Default';
            app.OffsetCleanSwitchLabel = uilabel(app.LiveanalysisparametersPanel);
            app.OffsetCleanSwitchLabel.Text = 'Offset Clean';
            app.OffsetCleanSwitch = uiswitch(app.LiveanalysisparametersPanel, 'slider');
            app.OffsetCleanSwitch.Value = 'On';
            app.AutoThresholdSwitchLabel = uilabel(app.LiveanalysisparametersPanel);
            app.AutoThresholdSwitchLabel.Text = 'Auto Threshold';
            app.AutoThresholdSwitch = uiswitch(app.LiveanalysisparametersPanel, 'slider');
            app.CalibrationDropDownLabel = uilabel(app.LiveanalysisparametersPanel);
            app.CalibrationDropDownLabel.Text = 'Calibration';
            app.CalibrationDropDown = uidropdown(app.LiveanalysisparametersPanel);
            app.CalibrationDropDown.Items = {'Default', 'None'};
            app.CalibrationDropDown.Value = 'Default';
            app.CenterXSpinnerLabel = uilabel(app.LiveanalysisparametersPanel);
            app.CenterXSpinnerLabel.Text = 'Center X';
            app.CenterXSpinner = uispinner(app.LiveanalysisparametersPanel);
            app.CenterXSpinner.RoundFractionalValues = 'on';
            app.CenterXSpinner.ValueDisplayFormat = '%d';
            app.CenterXSpinner.Value = 80;
            app.RadiusXSpinnerLabel = uilabel(app.LiveanalysisparametersPanel);
            app.RadiusXSpinnerLabel.Text = 'Radius X';
            app.RadiusXSpinner = uispinner(app.LiveanalysisparametersPanel);
            app.RadiusXSpinner.RoundFractionalValues = 'on';
            app.RadiusXSpinner.ValueDisplayFormat = '%d';
            app.RadiusXSpinner.Value = 5;
            app.RadiusYSpinnerLabel = uilabel(app.LiveanalysisparametersPanel);
            app.RadiusYSpinnerLabel.Text = 'Radius Y';
            app.RadiusYSpinner = uispinner(app.LiveanalysisparametersPanel);
            app.RadiusYSpinner.RoundFractionalValues = 'on';
            app.RadiusYSpinner.ValueDisplayFormat = '%d';
            app.RadiusYSpinner.Value = 15;
            app.CenterYSpinnerLabel = uilabel(app.LiveanalysisparametersPanel);
            app.CenterYSpinnerLabel.Text = 'Center Y';
            app.CenterYSpinner = uispinner(app.LiveanalysisparametersPanel);
            app.CenterYSpinner.RoundFractionalValues = 'on';
            app.CenterYSpinner.ValueDisplayFormat = '%d';
            app.CenterYSpinner.Value = 560;
            app.ThresholdTextAreaLabel = uilabel(app.LiveanalysisparametersPanel);
            app.ThresholdTextAreaLabel.Text = 'Threshold';
            app.ThresholdTextArea = uitextarea(app.LiveanalysisparametersPanel);
            app.ThresholdTextArea.Value = {'250*[1,1,1,1,1,1,1,1]'};
            app.UpdateFiguresSwitchLabel = uilabel(app.LiveanalysisparametersPanel);
            app.UpdateFiguresSwitchLabel.Text = 'Update Figures';
            app.UpdateFiguresSwitch = uiswitch(app.LiveanalysisparametersPanel, 'slider');
            app.UpdateFiguresSwitch.Value = 'On';
            app.DataModeButtonGroup = uibuttongroup(app.ControlTab);
            app.DataModeButtonGroup.Title = 'Data Mode';
            app.DataButton = uiradiobutton(app.DataModeButtonGroup);
            app.DataButton.Text = 'Data';
            app.Live1Button = uiradiobutton(app.DataModeButtonGroup);
            app.Live1Button.Text = 'Live 1';
            app.Live2Button = uiradiobutton(app.DataModeButtonGroup);
            app.Live2Button.Text = 'Live 2';
            app.Live4Button = uiradiobutton(app.DataModeButtonGroup);
            app.Live4Button.Text = 'Live 4';
            app.Live8Button = uiradiobutton(app.DataModeButtonGroup);
            app.Live8Button.Text = 'Live 8';
            app.Live8Button.Value = true;
            app.Live1CroppedButton = uiradiobutton(app.DataModeButtonGroup);
            app.Live1CroppedButton.Text = 'Live 1 Cropped';
            app.DMDTestButton = uiradiobutton(app.DataModeButtonGroup);
            app.DMDTestButton.Text = 'DMD Test';
            app.DataprocessingparametersPanel = uipanel(app.ControlTab);
            app.DataprocessingparametersPanel.Title = 'Data processing parameters';
            app.File0TextAreaLabel = uilabel(app.DataprocessingparametersPanel);
            app.File0TextAreaLabel.Text = 'File: 0';
            app.File0TextArea = uitextarea(app.DataprocessingparametersPanel);
            app.File0TextArea.Editable = 'off';
            app.BrowseFileButton = uibutton(app.DataprocessingparametersPanel, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse';
            app.PathLabel = uilabel(app.DataprocessingparametersPanel);
            app.PathLabel.Text = 'C:\Users\qmspc\Desktop\NewLabData\2022\';
            app.StatusOutputTextAreaLabel = uilabel(app.DataprocessingparametersPanel);
            app.StatusOutputTextAreaLabel.Text = 'Status Output';
            app.StatusOutputTextArea = uitextarea(app.DataprocessingparametersPanel);
            app.StatusOutputTextArea.Editable = 'off';
            app.ElapsedTimeLabel = uilabel(app.DataprocessingparametersPanel);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.UITable = uitable(app.DataprocessingparametersPanel);
            app.UITable.ColumnName = {'Parameter 1'; 'Parameter 2'; 'Parameter 3'};
            app.TotalElapsedTimeLabel = uilabel(app.DataprocessingparametersPanel);
            app.TotalElapsedTimeLabel.Text = 'Total Elapsed Time:';
            app.PostprocessingscriptsTextAreaLabel = uilabel(app.DataprocessingparametersPanel);
            app.PostprocessingscriptsTextAreaLabel.Text = 'Post-processing scripts';
            app.PostprocessingscriptsTextArea = uitextarea(app.DataprocessingparametersPanel);
            app.EXPORTDATAButton = uibutton(app.ControlTab, 'push');
            app.EXPORTDATAButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTDATAButtonPushed, true);
            app.EXPORTDATAButton.Text = 'EXPORT DATA';
            app.CCDSettingsPanel = uipanel(app.ControlTab);
            app.CCDSettingsPanel.Title = 'CCD Settings';
            app.InitializeButton = uibutton(app.CCDSettingsPanel, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.ExposuremsSpinnerLabel = uilabel(app.CCDSettingsPanel);
            app.ExposuremsSpinnerLabel.Text = 'Exposure (ms)';
            app.ExposuremsSpinner = uispinner(app.CCDSettingsPanel);
            app.ExposuremsSpinner.RoundFractionalValues = 'on';
            app.ExposuremsSpinner.ValueDisplayFormat = '%d';
            app.ExposuremsSpinner.Value = 200;
            app.CurrentCCDButtonGroup = uibuttongroup(app.CCDSettingsPanel);
            app.CurrentCCDButtonGroup.Title = 'Current CCD';
            app.UpperCCDButton = uiradiobutton(app.CurrentCCDButtonGroup);
            app.UpperCCDButton.Text = 'Upper CCD';
            app.LowerCCDButton = uiradiobutton(app.CurrentCCDButtonGroup);
            app.LowerCCDButton.Text = 'Lower CCD';
            app.LowerCCDButton.Value = true;
            app.ShutdownButton = uibutton(app.CCDSettingsPanel, 'push');
            app.ShutdownButton.ButtonPushedFcn = createCallbackFcn(app, @ShutdownButtonPushed, true);
            app.ShutdownButton.Text = 'Shutdown';
            app.UpperCCDLampLabel = uilabel(app.ControlTab);
            app.UpperCCDLampLabel.Text = 'Upper CCD';
            app.UpperCCDLamp = uilamp(app.ControlTab);
            app.LowerCCDLampLabel = uilabel(app.ControlTab);
            app.LowerCCDLampLabel.Text = 'Lower CCD';
            app.LowerCCDLamp = uilamp(app.ControlTab);
            app.StatusLampLabel = uilabel(app.ControlTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.ControlTab);
            app.STOPButton = uibutton(app.ControlTab, 'state');
            app.STOPButton.Text = 'STOP';
            app.PAUSEButton = uibutton(app.ControlTab, 'state');
            app.PAUSEButton.Text = 'PAUSE';
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.GridLayout = uigridlayout(app.FiguresTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'Image 1';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Tag = 'Image 2';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Tag = 'Image 3';
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Tag = 'Image 4';
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.Tag = 'Image 5';
            app.UIAxes6 = uiaxes(app.GridLayout);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.Tag = 'Image 6';
            app.UIAxes7 = uiaxes(app.GridLayout);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7.Tag = 'Image 7';
            app.UIAxes8 = uiaxes(app.GridLayout);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.Tag = 'Image 8';
            app.UIAxes9 = uiaxes(app.GridLayout);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes9.Tag = 'Plot 1';
            app.UIAxes10 = uiaxes(app.GridLayout);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes10.Tag = 'Plot 2';
            app.UIAxes11 = uiaxes(app.GridLayout);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes11.Tag = 'Plot 3';
            app.UIAxes12 = uiaxes(app.GridLayout);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes12.Tag = 'Plot 4';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ImageMenu = uimenu(app.ContextMenu);
            app.ImageMenu.Text = 'Image';
            app.Image1Menu = uimenu(app.ImageMenu);
            app.Image1Menu.Text = 'Image 1';
            app.Image2Menu = uimenu(app.ImageMenu);
            app.Image2Menu.Text = 'Image 2';
            app.Image3Menu = uimenu(app.ImageMenu);
            app.Image3Menu.Text = 'Image 3';
            app.Image4Menu = uimenu(app.ImageMenu);
            app.Image4Menu.Text = 'Image 4';
            app.Image5Menu = uimenu(app.ImageMenu);
            app.Image5Menu.Text = 'Image 5';
            app.Image6Menu = uimenu(app.ImageMenu);
            app.Image6Menu.Text = 'Image 6';
            app.Image7Menu = uimenu(app.ImageMenu);
            app.Image7Menu.Text = 'Image 7';
            app.Image8Menu = uimenu(app.ImageMenu);
            app.Image8Menu.Text = 'Image 8';
            app.HistogramMenu = uimenu(app.ContextMenu);
            app.HistogramMenu.Text = 'Histogram';
            app.Histogram1Menu = uimenu(app.HistogramMenu);
            app.Histogram1Menu.Text = 'Histogram 1';
            app.Histogram2Menu = uimenu(app.HistogramMenu);
            app.Histogram2Menu.Text = 'Histogram 2';
            app.Histogram3Menu = uimenu(app.HistogramMenu);
            app.Histogram3Menu.Text = 'Histogram 3';
            app.Histogram4Menu = uimenu(app.HistogramMenu);
            app.Histogram4Menu.Text = 'Histogram 4';
            app.Histogram5Menu = uimenu(app.HistogramMenu);
            app.Histogram5Menu.Text = 'Histogram 5';
            app.Histogram6Menu = uimenu(app.HistogramMenu);
            app.Histogram6Menu.Text = 'Histogram 6';
            app.Histogram7Menu = uimenu(app.HistogramMenu);
            app.Histogram7Menu.Text = 'Histogram 7';
            app.Histogram8Menu = uimenu(app.HistogramMenu);
            app.Histogram8Menu.Text = 'Histogram 8';
            app.AnalysisMenu = uimenu(app.ContextMenu);
            app.AnalysisMenu.Text = 'Analysis';
            app.ErrorandlossrateMenu = uimenu(app.AnalysisMenu);
            app.ErrorandlossrateMenu.Text = 'Error and loss rate';
            app.RetentionMenu = uimenu(app.AnalysisMenu);
            app.RetentionMenu.Text = 'Retention';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            app.UIAxes2.ContextMenu = app.ContextMenu;
            app.UIAxes3.ContextMenu = app.ContextMenu;
            app.UIAxes4.ContextMenu = app.ContextMenu;
            app.UIAxes5.ContextMenu = app.ContextMenu;
            app.UIAxes6.ContextMenu = app.ContextMenu;
            app.UIAxes7.ContextMenu = app.ContextMenu;
            app.UIAxes8.ContextMenu = app.ContextMenu;
            app.UIAxes9.ContextMenu = app.ContextMenu;
            app.UIAxes10.ContextMenu = app.ContextMenu;
            app.UIAxes11.ContextMenu = app.ContextMenu;
            app.UIAxes12.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataAnalyzer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @UIStartUp)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end