classdef ImageAcquisition_june14 < matlab.apps.AppBase
    %IMAGEACQUISITION_JUNE14 Camera Control software developed in MATLAB
    %for acquiring images.
    %   This app provides functionalities for:
    %   - Configure Andor and Zelux cameras
    %   - Acquire images with a programmable sequence
    %   - Display real-time analysis

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TotalRunTimeLabel               matlab.ui.control.Label
        RunNumLabel                     matlab.ui.control.Label
        ElapsedTimeLabel                matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ImageTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ROIradiusEditField              matlab.ui.control.NumericEditField
        ROIradiusEditFieldLabel         matlab.ui.control.Label
        ShowROIButton                   matlab.ui.control.Button
        Plot3IndexDropDown              matlab.ui.control.DropDown
        Plot3Label                      matlab.ui.control.Label
        Plot3StyleDropDown              matlab.ui.control.DropDown
        Plot1Label_2                    matlab.ui.control.Label
        Plot1Label                      matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        PauseContinueButton             matlab.ui.control.StateButton
        SaveButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.StateButton
        Axes1IndexDropDown              matlab.ui.control.DropDown
        Axes1StyleDropDown              matlab.ui.control.DropDown
        Axes2IndexDropDown              matlab.ui.control.DropDown
        Axes2DropDownLabel              matlab.ui.control.Label
        Axes2StyleDropDown              matlab.ui.control.DropDown
        Plot1IndexDropDown              matlab.ui.control.DropDown
        Plot1StyleDropDown              matlab.ui.control.DropDown
        Plot2IndexDropDown              matlab.ui.control.DropDown
        Plot2StyleDropDown              matlab.ui.control.DropDown
        Axes1DropDownLabel              matlab.ui.control.Label
        SmallAxes3                      matlab.ui.control.UIAxes
        SmallAxes2                      matlab.ui.control.UIAxes
        SmallAxes1                      matlab.ui.control.UIAxes
        BigAxes2                        matlab.ui.control.UIAxes
        BigAxes1                        matlab.ui.control.UIAxes
        SettingTab                      matlab.ui.container.Tab
        AnalysisPanel                   matlab.ui.container.Panel
        DMDPatternScriptDropDown        matlab.ui.control.DropDown
        DMDPatternScriptDropDownLabel   matlab.ui.control.Label
        BackgroundOffsetDropDown        matlab.ui.control.DropDown
        BackgroundOffsetDropDownLabel   matlab.ui.control.Label
        LatticeCalibrationDropDown      matlab.ui.control.DropDown
        LatticeCalibrationDropDownLabel  matlab.ui.control.Label
        AcquisitionPanel                matlab.ui.container.Panel
        PoolingintervalSpinner          matlab.ui.control.Spinner
        PoolingintervalsLabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        InitializeAcquisitionButton     matlab.ui.control.Button
        AcquisitiontimeoutsSpinner      matlab.ui.control.Spinner
        AcquisitiontimeoutsSpinnerLabel  matlab.ui.control.Label
        NumberofacquisitionsSpinner     matlab.ui.control.Spinner
        NumberofacquisitionsSpinnerLabel  matlab.ui.control.Label
        EstimatedDatasetsizeLabel       matlab.ui.control.Label
        ZeluxPanel                      matlab.ui.container.Panel
        ZeluxEstimatedreadouttimeLabel  matlab.ui.control.Label
        ZeluxStatusLamp                 matlab.ui.control.Lamp
        CloseZeluxButton                matlab.ui.control.Button
        ConfigureZeluxButton            matlab.ui.control.Button
        ZeluxTriggermodeDropDown        matlab.ui.control.DropDown
        TriggermodeDropDown_3Label      matlab.ui.control.Label
        ZeluxExposuresSpinner           matlab.ui.control.Spinner
        ExposuresSpinner_3Label         matlab.ui.control.Label
        Andor19331Panel                 matlab.ui.container.Panel
        Andor19331TemperatureLabel      matlab.ui.control.Label
        CheckAndor19331TemperatureButton  matlab.ui.control.Button
        Andor19331EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19331ReadoutSpeedDropDown  matlab.ui.control.DropDown
        ReadoutspeedLabel_2             matlab.ui.control.Label
        Andor19331StatusLamp            matlab.ui.control.Lamp
        CloseAndor19331Button           matlab.ui.control.Button
        ConfigureAndor19331Button       matlab.ui.control.Button
        Andor19331TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDown_2Label      matlab.ui.control.Label
        Andor19331AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDown_2Label  matlab.ui.control.Label
        Andor19331ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinner_2Label         matlab.ui.control.Label
        Andor19330Panel                 matlab.ui.container.Panel
        Andor19330TemperatureLabel      matlab.ui.control.Label
        CheckAndor19330TemperatureButton  matlab.ui.control.Button
        Andor19330EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19330ReadoutSpeedDropDown  matlab.ui.control.DropDown
        ReadoutspeedLabel               matlab.ui.control.Label
        Andor19330StatusLamp            matlab.ui.control.Lamp
        CloseAndor19330Button           matlab.ui.control.Button
        ConfigureAndor19330Button       matlab.ui.control.Button
        Andor19330TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDownLabel        matlab.ui.control.Label
        Andor19330AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDownLabel    matlab.ui.control.Label
        Andor19330ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinnerLabel           matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        ExportSettingstoFileMenu        matlab.ui.container.Menu
        LoadSettingsfromFileMenu        matlab.ui.container.Menu
        SetCurrentSettingsasDefaultMenu  matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Setting = struct('Andor19330',  struct(), ...
                         'Andor19331',  struct(), ...
                         'Zelux',       struct(), ...
                         'Acquisition', struct(), ...
                         'Analysis',    struct())
        Layout =  struct('BigAxes1',    struct('Style',         'Image', ...
                                               'ImageIndex',    1, ...
                                               'ImageFormat',   'Background-subtracted', ...
                                               'ImageObj',      matlab.graphics.primitive.Image.empty), ...
                         'BigAxes2',    struct('Style',         'Image', ...
                                               'ImageIndex',    2, ...
                                               'ImageFormat',   'Background-subtracted', ...
                                               'ImageObj',      matlab.graphics.primitive.Image.empty), ...
                         'SmallAxes1',  struct('Style',         'Plot',...
                                               'ImageIndex',    1, ...
                                               'PlotContent',   'MeanCount', ...
                                               'PlotObj',       matlab.graphics.chart.primitive.Line.empty), ...
                         'SmallAxes2',  struct('Style',         'Plot', ...
                                               'ImageIndex',    2, ...
                                               'PlotContent',   'MeanCount', ...
                                               'PlotObj',       matlab.graphics.chart.primitive.Line.empty), ...
                         'SmallAxes3',  struct('Style',         'Plot', ...
                                               'ImageIndex',    1, ...
                                               'PlotContent',   'MeanCount', ...
                                               'PlotObj',       matlab.graphics.chart.primitive.Line.empty))
        CameraHandle = struct()
        Live = struct()
        Data = struct()
    end
    
    methods (Access = private)        
        function updateLayout(app)
            axes_names = fieldnames(app.Layout);
            for i = 1:length(axes_names)
                axes_name = axes_names{i};
                % app.Layout.(axes_name) = displayAxesContent(app.(axes_name), app.Layout.(axes_name), app.Live);
                app.Layout.(axes_name) = displayAxesContent(app.(axes_name), app.Layout.(axes_name), app.Live, app.Setting.Andor19330);
                % app.Layout.(axes_name) = displayAxesContent(app.(axes_name), app.Layout.(axes_name), app.Live, app.Setting.Andor19330, app.Setting.Analysis);
            end
            drawnow
        end

        function clearPlot(app)
            app.Layout.SmallAxes1.PlotObj = matlab.graphics.chart.primitive.Line.empty;
            app.Layout.SmallAxes2.PlotObj = matlab.graphics.chart.primitive.Line.empty;
            app.Layout.SmallAxes3.PlotObj = matlab.graphics.chart.primitive.Line.empty;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            % Get screen size to set the APP dimension
            offset = [20, 20, -70, -70];
            s = get(0, "MonitorPositions");
            position = s(1,:) + offset;
            for i = 2:size(s, 1)
                if isequal(s(i,3:4), [1920,1080])
                    position = s(i, :) + offset;
                end
            end
            app.UIFigure.Position = position;

            % Pre-populate the acquisition table
            % Or, load default settings from default.mat
            app.UITable.Data = table((1:8)', ...
                categorical( ...
                {'--inactive--', 'Zelux', 'Andor19330', 'Andor19331', '--inactive--', '--inactive--', '--inactive--', '--inactive--'}, ...
                {'Andor19330',      'Andor19331', 'Zelux',      '--inactive--'},'Ordinal',true)', ...
                {'Lattice',         'DMD',        'Image',      'Image',      '',             '',             '',             ''}', ...
                {'',                '',           '',           '',           '',             '',             '',             ''}', ...
                'VariableNames',{'Order', 'Camera', 'Label', 'Note'});

            % Pre-load the calibration file selection
            

        end

        % Close request function: UIFigure
        function closeUI(app, event)
            % Close Zelux connections
            closeZeluxCallback(app, event)            
            delete(app)
        end

        % Button pushed function: RunButton
        function run(app, event)
            % Initialize the acquisition (pre-allocate memory, configure
            % cameras)
            initializeAcquisition(app,event)

            timer_total = tic;

            % Run continuously until aborted
            while ~app.StopButton.Value
                
                % Arm the Zelux if it is not armed
                if ~app.PauseContinueButton.Value && ...
                        isfield(app.CameraHandle,'Zelux') && ...
                        ~isempty(app.CameraHandle.Zelux) && ...
                        ~app.CameraHandle.Zelux{3}
                    app.CameraHandle.Zelux{2}.Arm;
                    app.CameraHandle.Zelux{3} = true;
                end

                while ~app.PauseContinueButton.Value && ~app.StopButton.Value
                    timer = tic;

                    app.StatusLamp.Color = [0, 1, 1];
                    app.StatusLabel.Text = 'Acquiring......';

                    % Acquire data
                    app.Live.Image = acquireImage(app.Setting.Acquisition, app.CameraHandle);
                    elapsed_time1 = toc(timer);

                    app.StatusLamp.Color = [0, 1, 0];
                    app.StatusLabel.Text = 'Acquired';
                    
                    % Real-time processing
                    [app.Live.Signal, app.Live.Offset] = subtractBackground(app.Live.Image, app.Live.Background, app.Setting);
                    elapsed_time2 = toc(timer);

                    % Update layout Axes
                    updateLayout(app)
                    elapsed_time3 = toc(timer);

                    % Update data storage
                    app.Live.CurrentIndex = app.Live.CurrentIndex + 1;
                    app.Data = updateData(app.Data, app.Live.Image, app.Live.CurrentIndex);
                    app.RunNumLabel.Text = sprintf('Run Num: %d/%d', app.Live.CurrentIndex, app.Setting.Acquisition.NumAcquisitions);
                    elapsed_time4 = toc(timer);
                   
                    pause(0.1)
                    
                    app.TotalRunTimeLabel.Text = sprintf('Total Run Time: %.2f min', toc(timer_total)/60);
                    app.ElapsedTimeLabel.Text = sprintf('Elapsed time: %.2f s', elapsed_time4+0.1);
                    fprintf('Image %d/%d:\nTotal elapsed time: %.2f s, acquisition time: %.3f, processing time: %.3f s, rendering time: %.3f s, storage time: %.3f s\n', ...
                        app.Live.CurrentIndex, app.Setting.Acquisition.NumAcquisitions, ...
                        elapsed_time4+0.1, elapsed_time1+0.1, elapsed_time2-elapsed_time1, elapsed_time3-elapsed_time2, elapsed_time4-elapsed_time3)
                end
            
                % Disarm Zelux if it is available
                if isfield(app.CameraHandle,'Zelux') && ...
                        ~isempty(app.CameraHandle.Zelux) && ...
                        app.CameraHandle.Zelux{3}
                    app.CameraHandle.Zelux{2}.Disarm;
                    app.CameraHandle.Zelux{3} = false;
                end
                
                app.StatusLamp.Color = [1, 1, 0];
                app.StatusLabel.Text = 'Paused';
                pause(0.1)
            end

            disp(app.Live)
            
            clearPlot(app)
            app.StatusLamp.Color = [1, 0, 0];
            app.StatusLabel.Text = 'Stopped';
            app.StopButton.Value = false;
            fprintf('Acquisition stopped\n')
            
        end

        % Button pushed function: SaveButton
        function save(app, event)
            Data = app.Data;
            uisave('Data', 'Data')
            fprintf('Data saved.\n')
        end

        % Button pushed function: InitializeAcquisitionButton
        function initializeAcquisition(app, event)
            % Record the acquisition settings and get a list of active cameras
            app.Setting.Acquisition.SequenceTable = sortrows( ...
                app.UITable.Data(~(app.UITable.Data.Camera == '--inactive--'), :), 'Order');
            app.Setting.Acquisition.NumAcquisitions = app.NumberofacquisitionsSpinner.Value;
            app.Setting.Acquisition.Timeout = app.AcquisitiontimeoutsSpinner.Value;
            app.Setting.Acquisition.Pooling = app.PoolingintervalSpinner.Value;
            if isempty(app.Setting.Acquisition.SequenceTable)
                uialert(app.UIFigure, 'No active camera selected!', 'Error')
                return
            end

            % Update Analysis settings
            app.Setting.Analysis.BackgroundFile = app.BackgroundOffsetDropDown.Value;
            app.Setting.Analysis.LatticeFile = app.LatticeCalibrationDropDown.Value;
            app.Setting.Analysis.ToggleROI=0;
            app.Setting.Analysis.ROIRadius=8;

            % Configure all active cameras and save config to settings
            cameras = unique(app.Setting.Acquisition.SequenceTable.Camera);
            for i = 1:length(cameras)
                camera = char(cameras(i));
                switch camera
                    case 'Andor19330'
                        if ~isequal(app.Andor19330StatusLamp.Color, [0, 1, 0])
                            configureAndor19330(app,event)
                        end
                    case 'Andor19331'
                        if ~isequal(app.Andor19331StatusLamp.Color, [0, 1, 0])
                            configureAndor19331(app,event)
                        end
                    case 'Zelux'
                        if ~isequal(app.ZeluxStatusLamp.Color, [0, 1, 0])
                            configureZelux(app,event)
                        end
                end
            end
            app.Setting.Acquisition.ActiveCameras = cameras;
        
            % Initialize Data and Live to pre-allocate storage for image
            % acquisition and live statistics
            [app.Data, app.Live, dsize] = initializeData(app.Setting, app.CameraHandle);
            app.EstimatedDatasetsizeLabel.Text = sprintf('Estimated Dataset size: %g MB', ...
                dsize);

            % Update Layout content selection dropdown
            num_images = height(app.Setting.Acquisition.SequenceTable);
            axes_content = cell(1, num_images);
            for i = 1:num_images
                camera = char(app.Setting.Acquisition.SequenceTable.Camera(i));
                label = char(app.Setting.Acquisition.SequenceTable.Label{i});
                axes_content{i} = sprintf('%d_%s_%s',i,camera,label);
            end
            app.Axes1IndexDropDown.Items = axes_content;
            app.Axes2IndexDropDown.Items = axes_content;
            app.Plot1IndexDropDown.Items = axes_content;
            app.Plot2IndexDropDown.Items = axes_content;
            app.Axes1IndexDropDown.Value = app.Axes1IndexDropDown.Items{2};
            app.Axes2IndexDropDown.Value = app.Axes2IndexDropDown.Items{end};
            updateAxes1Index(app,event)
            updateAxes2Index(app,event)
            updatePlot1Index(app,event)
            updatePlot2Index(app,event)
% add lines here for Plot3Index and Plot3Style

        end

        % Button pushed function: CheckAndor19330TemperatureButton
        function checkAndor19330Temperature(app, event)
            [temperature, status] = checkTemperature(19330,app.CameraHandle);
            app.Andor19330TemperatureLabel.Text = sprintf("%s: %g",status,temperature);
        end

        % Button pushed function: CheckAndor19331TemperatureButton
        function checkAndor19331Temperature(app, event)
            [temperature, status] = checkTemperature(19331,app.CameraHandle);
            app.Andor19331TemperatureLabel.Text = sprintf("%s: %g",status,temperature);
        end

        % Button pushed function: ConfigureAndor19330Button
        function configureAndor19330(app, event)
            app.CameraHandle = initializeAndor(19330, app.CameraHandle, "verbose",false);

            % Store the settings to app.Setting
            app.Setting.Andor19330 = struct('Exposure',app.Andor19330ExposuresSpinner.Value, ...
                'External',strcmp(app.Andor19330TriggermodeDropDown.Value, 'External'), ...
                'NumFrames',str2double(app.Andor19330AcquisitionmodeDropDown.Value(end)), ...
                'ReadoutSpeed',app.Andor19330ReadoutSpeedDropDown.Value, ...
                'Cropped',strcmp(app.Andor19330AcquisitionmodeDropDown.Value,'Cropped Frame 1'));
            
            % Configure camera
            readout_time = setModeFK("num_frames",app.Setting.Andor19330.NumFrames, ...
                "crop",app.Setting.Andor19330.Cropped, ...
                "exposure",app.Setting.Andor19330.Exposure, ...
                "external_trigger",app.Setting.Andor19330.External, ...
                "horizontal_speed",app.Setting.Andor19330.ReadoutSpeed);

            % After configuration, set the indicator to Green (RGB) and
            % update estimation text
            app.Andor19330StatusLamp.Color = [0, 1, 0];
            app.Andor19330EstimatedreadouttimeLabel.Text = sprintf('Estimated readout/cycle time: %.3f s', ...
                readout_time);

        end

        % Button pushed function: ConfigureAndor19331Button
        function configureAndor19331(app, event)
            app.CameraHandle = initializeAndor(19331, app.CameraHandle, "verbose",false);
            
            % Store the settings to app.Setting
            app.Setting.Andor19331 = struct('Exposure',app.Andor19331ExposuresSpinner.Value, ...
                'External',strcmp(app.Andor19331TriggermodeDropDown.Value, 'External'), ...
                'NumFrames',str2double(app.Andor19331AcquisitionmodeDropDown.Value(end)), ...
                'ReadoutSpeed',app.Andor19331ReadoutSpeedDropDown.Value,...
                'Cropped',strcmp(app.Andor19331AcquisitionmodeDropDown.Value,'Cropped Frame 1'));
            
            % Configure camera
            readout_time = setModeFK("num_frames",app.Setting.Andor19331.NumFrames, ...
                "crop",app.Setting.Andor19331.Cropped, ...
                "exposure",app.Setting.Andor19331.Exposure, ...
                "external_trigger",app.Setting.Andor19331.External, ...
                "horizontal_speed",app.Setting.Andor19331.ReadoutSpeed);

            % After configuration, set the indicator to Green (RGB)
            app.Andor19331StatusLamp.Color = [0, 1, 0];
            app.Andor19331EstimatedreadouttimeLabel.Text = sprintf('Estimated readout/cycle time: %.3f s', ...
                readout_time);

        end

        % Button pushed function: ConfigureZeluxButton
        function configureZelux(app, event)
            % Store the settings to app.Setting
            app.Setting.Zelux = struct('Exposure', app.ZeluxExposuresSpinner.Value, ...
                'External', strcmp(app.ZeluxTriggermodeDropDown.Value, 'External'), ...
                'XPixels', 1440, ...
                'YPixels', 1080);
            
            if isfield(app.CameraHandle, 'Zelux') && ~isempty(app.CameraHandle.Zelux)
                closeZelux(app.CameraHandle.Zelux{1:2})
            end
            [tlCameraSDK, tlCamera] = initializeZelux("exposure",app.Setting.Zelux.Exposure, ...
                "external_trigger",app.Setting.Zelux.External);
            
            app.CameraHandle.Zelux = {tlCameraSDK, tlCamera, true};
            app.CameraHandle.Zelux{2}.Disarm;
            app.CameraHandle.Zelux{3} = false;

            % After configuration, set the indicator to Green (RGB)
            app.ZeluxStatusLamp.Color = [0, 1, 0];
            app.ZeluxEstimatedreadouttimeLabel.Text = sprintf('Estimated readout time: %.3f s', double(tlCamera.SensorReadoutTime_ns)/1e9);

        end

        % Button pushed function: CloseAndor19330Button
        function closeAndor19330(app, event)
            closeAndor(19330, app.CameraHandle)

            % After closing, set the indicator to Red (RGB)
            app.Andor19330StatusLamp.Color = [1, 0, 0];

        end

        % Button pushed function: CloseAndor19331Button
        function closeAndor19331(app, event)
            closeAndor(19331, app.CameraHandle)

            % After closing, set the indicator to Red (RGB)
            app.Andor19331StatusLamp.Color = [1, 0, 0];

        end

        % Button pushed function: CloseZeluxButton
        function closeZeluxCallback(app, event)
            if isfield(app.CameraHandle, 'Zelux') & ~isempty(app.CameraHandle.Zelux)
                closeZelux(app.CameraHandle.Zelux{1:2})
                app.CameraHandle.Zelux = {};
            end

            % After closing, set the indicator to Red (RGB)
            app.ZeluxStatusLamp.Color = [1, 0, 0];

        end

        % Value changed function: Andor19330AcquisitionmodeDropDown, 
        % ...and 3 other components
        function updateAndor19330Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.Andor19330StatusLamp.Color, [0, 1, 0])
                app.Andor19330StatusLamp.Color = [1, 1, 0];
            end
        end

        % Value changed function: Andor19331AcquisitionmodeDropDown, 
        % ...and 3 other components
        function updateAndor19331Status(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.Andor19331StatusLamp.Color, [0, 1, 0])
                app.Andor19331StatusLamp.Color = [1, 1, 0];
            end
        end

        % Value changed function: ZeluxExposuresSpinner, 
        % ...and 1 other component
        function updateZeluxStatus(app, event)
            % Setting the status to "Yellow" to indicate the settings are
            % not active
            if isequal(app.ZeluxStatusLamp.Color, [0, 1, 0])
                app.ZeluxStatusLamp.Color = [1, 1, 0];
            end
        end

        % Value changed function: Axes1IndexDropDown
        function updateAxes1Index(app, event)
            app.Layout.BigAxes1.ImageIndex = app.Axes1IndexDropDown.ValueIndex;            
        end

        % Value changed function: Axes2IndexDropDown
        function updateAxes2Index(app, event)
            app.Layout.BigAxes2.ImageIndex = app.Axes2IndexDropDown.ValueIndex;            
        end

        % Value changed function: Plot1IndexDropDown
        function updatePlot1Index(app, event)
            app.Layout.SmallAxes1.ImageIndex = app.Plot1IndexDropDown.ValueIndex;
            app.Layout.SmallAxes1.PlotObj =  matlab.graphics.chart.primitive.Line.empty;
        end

        % Value changed function: Plot2IndexDropDown
        function updatePlot2Index(app, event)
            app.Layout.SmallAxes2.ImageIndex = app.Plot2IndexDropDown.ValueIndex;      
            app.Layout.SmallAxes2.PlotObj =  matlab.graphics.chart.primitive.Line.empty;
        end

        % Value changed function: Axes1StyleDropDown
        function updateAxes1Content(app, event)
            app.Layout.BigAxes1.ImageFormat = app.Axes1StyleDropDown.Value;
        end

        % Value changed function: Axes2StyleDropDown
        function updateAxes2Content(app, event)
            app.Layout.BigAxes2.ImageFormat = app.Axes2StyleDropDown.Value;
        end

        % Value changed function: Plot1StyleDropDown
        function updatePlot1Content(app, event)
            app.Layout.SmallAxes1.PlotContent = app.Plot1StyleDropDown.Value;
            app.Layout.SmallAxes1.PlotObj =  matlab.graphics.chart.primitive.Line.empty;
        end

        % Value changed function: Plot2StyleDropDown
        function updatePlot2Content(app, event)
            app.Layout.SmallAxes2.PlotContent = app.Plot2StyleDropDown.Value;
            app.Layout.SmallAxes2.PlotObj =  matlab.graphics.chart.primitive.Line.empty;
        end

        % Menu selected function: ExportSettingstoFileMenu
        function exportSettings(app, event)
            Setting = app.Setting;
            uisave('Setting', sprintf('Setting_%s',string(datetime('now','Format','yyyyMMdd'))))
        end

        % Menu selected function: LoadSettingsfromFileMenu
        function loadSettings(app, event)
            
        end

        % Menu selected function: SetCurrentSettingsasDefaultMenu
        function setSettingsDefault(app, event)
            
        end

        % Button down function: SmallAxes3
        function SmallAxes3ButtonDown(app, event)
    
        end

        % Value changed function: Plot3IndexDropDown
        function Plot3IndexDropDownValueChanged(app, event)
            % value = app.Plot3IndexDropDown.Value;
            app.Layout.SmallAxes3.ImageIndex = app.Plot3IndexDropDown.ValueIndex;
            app.Layout.SmallAxes3.PlotObj =  matlab.graphics.chart.primitive.Line.empty;
  
        end

        % Value changed function: Plot3StyleDropDown
        function Plot3StyleDropDownValueChanged(app, event)
             
            % value = app.Plot3StyleDropDown.Value;
            app.Layout.SmallAxes3.PlotContent = app.Plot3StyleDropDown.Value;
            app.Layout.SmallAxes3.PlotObj =  matlab.graphics.chart.primitive.Line.empty;
      
            
        end

        % Button pushed function: ShowROIButton
        function toggle(app, event)
            % update Setting.Analysis to have ToggleROI=0/1
            if app.Setting.Analysis.ToggleROI==0
                app.Setting.Analysis.ToggleROI=1;
            else
                app.Setting.Analysis.ToggleROI=0;
            end
        end

        % Callback function
        function ROIRadius(app, event)
            value = app.ROIradiusEditField.Value;
            
        end

        % Value changed function: ROIradiusEditField
        function ROIRadiusEditFieldValueChanged(app, event)
            app.Setting.Analysis.ROIRadius = app.ROIradiusEditField.Value;
            % Connect value saved in settings to analysis code
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeUI, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.GridLayout = uigridlayout(app.ImageTab);
            app.BigAxes1 = uiaxes(app.GridLayout);
            app.BigAxes2 = uiaxes(app.GridLayout);
            title(app.BigAxes2, ' ')
            app.SmallAxes1 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes1, 'X')
            ylabel(app.SmallAxes1, 'Y')
            zlabel(app.SmallAxes1, 'Z')
            app.SmallAxes2 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes2, 'X')
            ylabel(app.SmallAxes2, 'Y')
            zlabel(app.SmallAxes2, 'Z')
            app.SmallAxes3 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes3, 'X')
            ylabel(app.SmallAxes3, 'Y')
            zlabel(app.SmallAxes3, 'Z')
            app.SmallAxes3.ButtonDownFcn = createCallbackFcn(app, @SmallAxes3ButtonDown, true);
            app.SmallAxes3.Tag = 'SmallAxes3';
            app.Axes1DropDownLabel = uilabel(app.GridLayout);
            app.Axes1DropDownLabel.Text = 'Axes 1';
            app.Plot2StyleDropDown = uidropdown(app.GridLayout);
            app.Plot2StyleDropDown.Items = {'Mean Count', 'Max Count', 'GaussXCenter', 'Filling'};
            app.Plot2StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlot2Content, true);
            app.Plot2StyleDropDown.Value = 'Mean Count';
            app.Plot2IndexDropDown = uidropdown(app.GridLayout);
            app.Plot2IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Plot2IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlot2Index, true);
            app.Plot2IndexDropDown.Value = 'Image_1';
            app.Plot1StyleDropDown = uidropdown(app.GridLayout);
            app.Plot1StyleDropDown.Items = {'Mean Count', 'Max Count', 'GaussXCenter', 'Filling'};
            app.Plot1StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlot1Content, true);
            app.Plot1StyleDropDown.Value = 'Mean Count';
            app.Plot1IndexDropDown = uidropdown(app.GridLayout);
            app.Plot1IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Plot1IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePlot1Index, true);
            app.Plot1IndexDropDown.Value = 'Image_1';
            app.Axes2StyleDropDown = uidropdown(app.GridLayout);
            app.Axes2StyleDropDown.Items = {'Raw', 'Background-subtracted', 'Background offset'};
            app.Axes2StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes2Content, true);
            app.Axes2StyleDropDown.Value = 'Background-subtracted';
            app.Axes2DropDownLabel = uilabel(app.GridLayout);
            app.Axes2DropDownLabel.Text = 'Axes 2';
            app.Axes2IndexDropDown = uidropdown(app.GridLayout);
            app.Axes2IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Axes2IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes2Index, true);
            app.Axes2IndexDropDown.Value = 'Image_2';
            app.Axes1StyleDropDown = uidropdown(app.GridLayout);
            app.Axes1StyleDropDown.Items = {'Raw', 'Background-subtracted', 'Background offset'};
            app.Axes1StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes1Content, true);
            app.Axes1StyleDropDown.Value = 'Background-subtracted';
            app.Axes1IndexDropDown = uidropdown(app.GridLayout);
            app.Axes1IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Axes1IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAxes1Index, true);
            app.Axes1IndexDropDown.Value = 'Image_1';
            app.StopButton = uibutton(app.GridLayout, 'state');
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.PauseContinueButton = uibutton(app.GridLayout, 'state');
            app.PauseContinueButton.Text = 'Pause/Continue';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunButton.Text = 'Run';
            app.Plot1Label = uilabel(app.GridLayout);
            app.Plot1Label.Text = 'Plot 1';
            app.Plot1Label_2 = uilabel(app.GridLayout);
            app.Plot1Label_2.Text = 'Plot 2';
            app.Plot3StyleDropDown = uidropdown(app.GridLayout);
            app.Plot3StyleDropDown.Items = {'Mean Count', 'Max Count', 'GaussXCenter', 'Filling'};
            app.Plot3StyleDropDown.ValueChangedFcn = createCallbackFcn(app, @Plot3StyleDropDownValueChanged, true);
            app.Plot3StyleDropDown.Value = 'Mean Count';
            app.Plot3Label = uilabel(app.GridLayout);
            app.Plot3Label.Text = 'Plot 3';
            app.Plot3IndexDropDown = uidropdown(app.GridLayout);
            app.Plot3IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Plot3IndexDropDown.ValueChangedFcn = createCallbackFcn(app, @Plot3IndexDropDownValueChanged, true);
            app.Plot3IndexDropDown.Value = 'Image_1';
            app.ShowROIButton = uibutton(app.GridLayout, 'push');
            app.ShowROIButton.ButtonPushedFcn = createCallbackFcn(app, @toggle, true);
            app.ShowROIButton.Text = 'Show ROI';
            app.ROIradiusEditFieldLabel = uilabel(app.GridLayout);
            app.ROIradiusEditFieldLabel.Text = 'ROI radius';
            app.ROIradiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.ROIradiusEditField.ValueChangedFcn = createCallbackFcn(app, @ROIRadiusEditFieldValueChanged, true);
            app.ROIradiusEditField.Value = 1;
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.Andor19330Panel = uipanel(app.SettingTab);
            app.Andor19330Panel.Title = 'Andor 19330';
            app.ExposuresSpinnerLabel = uilabel(app.Andor19330Panel);
            app.ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.Andor19330ExposuresSpinner = uispinner(app.Andor19330Panel);
            app.Andor19330ExposuresSpinner.Step = 0.1;
            app.Andor19330ExposuresSpinner.Limits = [0 Inf];
            app.Andor19330ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.Andor19330AcquisitionmodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped Frame 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19330AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330AcquisitionmodeDropDown.Value = 'Full Frame 1';
            app.TriggermodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.Andor19330TriggermodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19330TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330TriggermodeDropDown.Value = 'External';
            app.ConfigureAndor19330Button = uibutton(app.Andor19330Panel, 'push');
            app.ConfigureAndor19330Button.ButtonPushedFcn = createCallbackFcn(app, @configureAndor19330, true);
            app.ConfigureAndor19330Button.Text = 'Configure Andor 19330';
            app.CloseAndor19330Button = uibutton(app.Andor19330Panel, 'push');
            app.CloseAndor19330Button.ButtonPushedFcn = createCallbackFcn(app, @closeAndor19330, true);
            app.CloseAndor19330Button.Text = 'Close Andor 19330';
            app.Andor19330StatusLamp = uilamp(app.Andor19330Panel);
            app.ReadoutspeedLabel = uilabel(app.Andor19330Panel);
            app.ReadoutspeedLabel.Text = 'Readout speed';
            app.Andor19330ReadoutSpeedDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19330ReadoutSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19330Status, true);
            app.Andor19330ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19330EstimatedreadouttimeLabel = uilabel(app.Andor19330Panel);
            app.Andor19330EstimatedreadouttimeLabel.Text = 'Estimated readout/cycle time: ';
            app.CheckAndor19330TemperatureButton = uibutton(app.Andor19330Panel, 'push');
            app.CheckAndor19330TemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @checkAndor19330Temperature, true);
            app.CheckAndor19330TemperatureButton.Text = 'Check Temperature';
            app.Andor19330TemperatureLabel = uilabel(app.Andor19330Panel);
            app.Andor19330TemperatureLabel.Text = '';
            app.Andor19331Panel = uipanel(app.SettingTab);
            app.Andor19331Panel.Title = 'Andor 19331';
            app.ExposuresSpinner_2Label = uilabel(app.Andor19331Panel);
            app.ExposuresSpinner_2Label.Text = 'Exposure (s)';
            app.Andor19331ExposuresSpinner = uispinner(app.Andor19331Panel);
            app.Andor19331ExposuresSpinner.Step = 0.1;
            app.Andor19331ExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDown_2Label = uilabel(app.Andor19331Panel);
            app.AcquisitionmodeDropDown_2Label.Text = 'Acquisition mode';
            app.Andor19331AcquisitionmodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped Frame 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19331AcquisitionmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331AcquisitionmodeDropDown.Value = 'Full Frame 1';
            app.TriggermodeDropDown_2Label = uilabel(app.Andor19331Panel);
            app.TriggermodeDropDown_2Label.Text = 'Trigger mode';
            app.Andor19331TriggermodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19331TriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331TriggermodeDropDown.Value = 'External';
            app.ConfigureAndor19331Button = uibutton(app.Andor19331Panel, 'push');
            app.ConfigureAndor19331Button.ButtonPushedFcn = createCallbackFcn(app, @configureAndor19331, true);
            app.ConfigureAndor19331Button.Text = 'Configure Andor 19331';
            app.CloseAndor19331Button = uibutton(app.Andor19331Panel, 'push');
            app.CloseAndor19331Button.ButtonPushedFcn = createCallbackFcn(app, @closeAndor19331, true);
            app.CloseAndor19331Button.Text = 'Close Andor 19331';
            app.Andor19331StatusLamp = uilamp(app.Andor19331Panel);
            app.ReadoutspeedLabel_2 = uilabel(app.Andor19331Panel);
            app.ReadoutspeedLabel_2.Text = 'Readout speed';
            app.Andor19331ReadoutSpeedDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19331ReadoutSpeedDropDown.ValueChangedFcn = createCallbackFcn(app, @updateAndor19331Status, true);
            app.Andor19331ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19331EstimatedreadouttimeLabel = uilabel(app.Andor19331Panel);
            app.Andor19331EstimatedreadouttimeLabel.Text = 'Estimated readout/cycle time: ';
            app.CheckAndor19331TemperatureButton = uibutton(app.Andor19331Panel, 'push');
            app.CheckAndor19331TemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @checkAndor19331Temperature, true);
            app.CheckAndor19331TemperatureButton.Text = 'Check Temperature';
            app.Andor19331TemperatureLabel = uilabel(app.Andor19331Panel);
            app.Andor19331TemperatureLabel.Text = '';
            app.ZeluxPanel = uipanel(app.SettingTab);
            app.ZeluxPanel.Title = 'Zelux';
            app.ExposuresSpinner_3Label = uilabel(app.ZeluxPanel);
            app.ExposuresSpinner_3Label.Text = 'Exposure (s)';
            app.ZeluxExposuresSpinner = uispinner(app.ZeluxPanel);
            app.ZeluxExposuresSpinner.Step = 0.1;
            app.ZeluxExposuresSpinner.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxExposuresSpinner.Value = 1e-05;
            app.TriggermodeDropDown_3Label = uilabel(app.ZeluxPanel);
            app.TriggermodeDropDown_3Label.Text = 'Trigger mode';
            app.ZeluxTriggermodeDropDown = uidropdown(app.ZeluxPanel);
            app.ZeluxTriggermodeDropDown.Items = {'External', 'Internal'};
            app.ZeluxTriggermodeDropDown.ValueChangedFcn = createCallbackFcn(app, @updateZeluxStatus, true);
            app.ZeluxTriggermodeDropDown.Value = 'External';
            app.ConfigureZeluxButton = uibutton(app.ZeluxPanel, 'push');
            app.ConfigureZeluxButton.ButtonPushedFcn = createCallbackFcn(app, @configureZelux, true);
            app.ConfigureZeluxButton.Text = 'Configure Zelux';
            app.CloseZeluxButton = uibutton(app.ZeluxPanel, 'push');
            app.CloseZeluxButton.ButtonPushedFcn = createCallbackFcn(app, @closeZeluxCallback, true);
            app.CloseZeluxButton.Text = 'Close Zelux';
            app.ZeluxStatusLamp = uilamp(app.ZeluxPanel);
            app.ZeluxEstimatedreadouttimeLabel = uilabel(app.ZeluxPanel);
            app.ZeluxEstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.AcquisitionPanel = uipanel(app.SettingTab);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.EstimatedDatasetsizeLabel = uilabel(app.AcquisitionPanel);
            app.EstimatedDatasetsizeLabel.Text = 'Estimated Dataset size: ';
            app.NumberofacquisitionsSpinnerLabel = uilabel(app.AcquisitionPanel);
            app.NumberofacquisitionsSpinnerLabel.Text = 'Number of acquisitions';
            app.NumberofacquisitionsSpinner = uispinner(app.AcquisitionPanel);
            app.NumberofacquisitionsSpinner.ValueDisplayFormat = '%d';
            app.NumberofacquisitionsSpinner.Value = 20;
            app.AcquisitiontimeoutsSpinnerLabel = uilabel(app.AcquisitionPanel);
            app.AcquisitiontimeoutsSpinnerLabel.Text = 'Acquisition time out (s)';
            app.AcquisitiontimeoutsSpinner = uispinner(app.AcquisitionPanel);
            app.AcquisitiontimeoutsSpinner.Value = 1000;
            app.InitializeAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.InitializeAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @initializeAcquisition, true);
            app.InitializeAcquisitionButton.Text = 'Initialize Acquisition';
            app.UITable = uitable(app.AcquisitionPanel);
            app.UITable.ColumnName = {'Order'; 'Camera'; 'Label'; 'Note'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false];
            app.UITable.ColumnEditable = [false true true true];
            app.PoolingintervalsLabel = uilabel(app.AcquisitionPanel);
            app.PoolingintervalsLabel.Text = 'Pooling interval (s)';
            app.PoolingintervalSpinner = uispinner(app.AcquisitionPanel);
            app.PoolingintervalSpinner.Value = 0.01;
            app.AnalysisPanel = uipanel(app.SettingTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.LatticeCalibrationDropDownLabel = uilabel(app.AnalysisPanel);
            app.LatticeCalibrationDropDownLabel.Text = 'Lattice Calibration';
            app.LatticeCalibrationDropDown = uidropdown(app.AnalysisPanel);
            app.LatticeCalibrationDropDown.Items = {''};
            app.LatticeCalibrationDropDown.Value = '';
            app.BackgroundOffsetDropDownLabel = uilabel(app.AnalysisPanel);
            app.BackgroundOffsetDropDownLabel.Text = 'Background Offset';
            app.BackgroundOffsetDropDown = uidropdown(app.AnalysisPanel);
            app.BackgroundOffsetDropDown.Items = {'StatBackground_20240327_HSSpeed=2_VSSpeed=1'};
            app.BackgroundOffsetDropDown.Value = 'StatBackground_20240327_HSSpeed=2_VSSpeed=1';
            app.DMDPatternScriptDropDownLabel = uilabel(app.AnalysisPanel);
            app.DMDPatternScriptDropDownLabel.Text = 'DMD Pattern Script';
            app.DMDPatternScriptDropDown = uidropdown(app.AnalysisPanel);
            app.DMDPatternScriptDropDown.Items = {''};
            app.DMDPatternScriptDropDown.Value = '';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ElapsedTimeLabel = uilabel(app.UIFigure);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.RunNumLabel = uilabel(app.UIFigure);
            app.RunNumLabel.Text = 'Run Num: ';
            app.TotalRunTimeLabel = uilabel(app.UIFigure);
            app.TotalRunTimeLabel.Text = 'Total Run Time:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportSettingstoFileMenu = uimenu(app.ContextMenu);
            app.ExportSettingstoFileMenu.MenuSelectedFcn = createCallbackFcn(app, @exportSettings, true);
            app.ExportSettingstoFileMenu.Text = 'Export Settings to File';
            app.LoadSettingsfromFileMenu = uimenu(app.ContextMenu);
            app.LoadSettingsfromFileMenu.MenuSelectedFcn = createCallbackFcn(app, @loadSettings, true);
            app.LoadSettingsfromFileMenu.Text = 'Load Settings from File';
            app.SetCurrentSettingsasDefaultMenu = uimenu(app.ContextMenu);
            app.SetCurrentSettingsasDefaultMenu.MenuSelectedFcn = createCallbackFcn(app, @setSettingsDefault, true);
            app.SetCurrentSettingsasDefaultMenu.Text = 'Set Current Settings as Default';
            % Assign app.ContextMenu
            app.SettingTab.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu2);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu2);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.SmallAxes3.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisition_june14

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startApp)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end