classdef ImageAcquisitorNew < matlab.apps.AppBase
    %IMAGEACQUISITORNEW Camera Control software developed in MATLAB for
    %acquiring images.
    %   This app provides functionalities for:
    %   - Configure Andor and Zelux cameras
    %   - Acquire images with a programmable sequence
    %   - Display real-time analysis

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TotalRunTimeLabel               matlab.ui.control.Label
        RunNumLabel                     matlab.ui.control.Label
        ElapsedTimeLabel                matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        LiveTab                         matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ROIradiusEditField              matlab.ui.control.NumericEditField
        ROIradiusEditFieldLabel         matlab.ui.control.Label
        ShowROIButton                   matlab.ui.control.Button
        InitAcquisitionButton           matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.StateButton
        RunAutoSaveButton               matlab.ui.control.Button
        Axes1IndexDropDown              matlab.ui.control.DropDown
        Axes1StyleDropDown              matlab.ui.control.DropDown
        Axes2IndexDropDown              matlab.ui.control.DropDown
        Axes2StyleDropDown              matlab.ui.control.DropDown
        Plot1IndexDropDown              matlab.ui.control.DropDown
        Plot1StyleDropDown              matlab.ui.control.DropDown
        Plot2IndexDropDown              matlab.ui.control.DropDown
        Plot2StyleDropDown              matlab.ui.control.DropDown
        Plot3IndexDropDown              matlab.ui.control.DropDown
        Plot3StyleDropDown              matlab.ui.control.DropDown
        SmallAxes3                      matlab.ui.control.UIAxes
        SmallAxes2                      matlab.ui.control.UIAxes
        SmallAxes1                      matlab.ui.control.UIAxes
        BigAxes2                        matlab.ui.control.UIAxes
        BigAxes1                        matlab.ui.control.UIAxes
        SettingTab                      matlab.ui.container.Tab
        CameraControlPanel              matlab.ui.container.Panel
        ZeluxPanel                      matlab.ui.container.Panel
        ZeluxEstimatedreadouttimeLabel  matlab.ui.control.Label
        ZeluxStatusLamp                 matlab.ui.control.Lamp
        CloseZeluxButton                matlab.ui.control.Button
        ConfigureZeluxButton            matlab.ui.control.Button
        ZeluxTriggermodeDropDown        matlab.ui.control.DropDown
        TriggermodeDropDown_3Label      matlab.ui.control.Label
        ZeluxExposuresSpinner           matlab.ui.control.Spinner
        ExposuresSpinner_3Label         matlab.ui.control.Label
        Andor19331Panel                 matlab.ui.container.Panel
        Andor19331TemperatureLabel      matlab.ui.control.Label
        CheckAndor19331TemperatureButton  matlab.ui.control.Button
        Andor19331EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19331ReadoutSpeedDropDown  matlab.ui.control.DropDown
        ReadoutspeedLabel_2             matlab.ui.control.Label
        Andor19331StatusLamp            matlab.ui.control.Lamp
        CloseAndor19331Button           matlab.ui.control.Button
        ConfigureAndor19331Button       matlab.ui.control.Button
        Andor19331TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDown_2Label      matlab.ui.control.Label
        Andor19331AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDown_2Label  matlab.ui.control.Label
        Andor19331ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinner_2Label         matlab.ui.control.Label
        Andor19330Panel                 matlab.ui.container.Panel
        Andor19330TemperatureLabel      matlab.ui.control.Label
        CheckAndor19330TemperatureButton  matlab.ui.control.Button
        Andor19330EstimatedreadouttimeLabel  matlab.ui.control.Label
        Andor19330ReadoutSpeedDropDown  matlab.ui.control.DropDown
        ReadoutspeedLabel               matlab.ui.control.Label
        Andor19330StatusLamp            matlab.ui.control.Lamp
        CloseAndor19330Button           matlab.ui.control.Button
        ConfigureAndor19330Button       matlab.ui.control.Button
        Andor19330TriggermodeDropDown   matlab.ui.control.DropDown
        TriggermodeDropDownLabel        matlab.ui.control.Label
        Andor19330AcquisitionmodeDropDown  matlab.ui.control.DropDown
        AcquisitionmodeDropDownLabel    matlab.ui.control.Label
        Andor19330ExposuresSpinner      matlab.ui.control.Spinner
        ExposuresSpinnerLabel           matlab.ui.control.Label
        AnalysisPanel                   matlab.ui.container.Panel
        DMDPatternScriptDropDown        matlab.ui.control.DropDown
        DMDPatternScriptDropDownLabel   matlab.ui.control.Label
        BackgroundOffsetDropDown        matlab.ui.control.DropDown
        BackgroundOffsetDropDownLabel   matlab.ui.control.Label
        LatticeCalibrationDropDown      matlab.ui.control.DropDown
        LatticeCalibrationDropDownLabel  matlab.ui.control.Label
        AcquisitionPanel                matlab.ui.container.Panel
        RefreshIntervalSpinner          matlab.ui.control.Spinner
        PoolingintervalsLabel           matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        InitializeAcquisitionButton     matlab.ui.control.Button
        AcquisitiontimeoutsSpinner      matlab.ui.control.Spinner
        AcquisitiontimeoutsSpinnerLabel  matlab.ui.control.Label
        NumberofacquisitionsSpinner     matlab.ui.control.Spinner
        NumberofacquisitionsSpinnerLabel  matlab.ui.control.Label
        EstimatedDatasetsizeLabel       matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        ExportSettingstoFileMenu        matlab.ui.container.Menu
        LoadSettingsfromFileMenu        matlab.ui.container.Menu
        SetCurrentSettingsasDefaultMenu  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Acquisitor = Acquisitor()
    end
    
    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            % Get screen size to set the APP dimension
            offset = [20, 20, -70, -70];
            s = get(0, "MonitorPositions");
            position = s(1,:) + offset;
            for i = 2:size(s, 1)
                if isequal(s(i,3:4), [1920,1080])
                    position = s(i, :) + offset;
                end
            end
            app.UIFigure.Position = position;

            % Pre-populate the acquisition table
            app.UITable.Data = app.Acquisitor.AcquisitionConfig.SequenceTable;

            % Pre-load the calibration file selection
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LiveTab = uitab(app.TabGroup);
            app.LiveTab.Title = 'Live';
            app.GridLayout = uigridlayout(app.LiveTab);
            app.BigAxes1 = uiaxes(app.GridLayout);
            app.BigAxes2 = uiaxes(app.GridLayout);
            title(app.BigAxes2, ' ')
            app.SmallAxes1 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes1, 'X')
            ylabel(app.SmallAxes1, 'Y')
            zlabel(app.SmallAxes1, 'Z')
            app.SmallAxes2 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes2, 'X')
            ylabel(app.SmallAxes2, 'Y')
            zlabel(app.SmallAxes2, 'Z')
            app.SmallAxes3 = uiaxes(app.GridLayout);
            xlabel(app.SmallAxes3, 'X')
            ylabel(app.SmallAxes3, 'Y')
            zlabel(app.SmallAxes3, 'Z')
            app.SmallAxes3.Tag = 'SmallAxes3';
            app.Plot3StyleDropDown = uidropdown(app.GridLayout);
            app.Plot3StyleDropDown.Items = {'Mean Count', 'Max Count', 'GaussXCenter', 'Filling'};
            app.Plot3StyleDropDown.Value = 'Mean Count';
            app.Plot3IndexDropDown = uidropdown(app.GridLayout);
            app.Plot3IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Plot3IndexDropDown.Value = 'Image_1';
            app.Plot2StyleDropDown = uidropdown(app.GridLayout);
            app.Plot2StyleDropDown.Items = {'Mean Count', 'Max Count', 'GaussXCenter', 'Filling'};
            app.Plot2StyleDropDown.Value = 'Mean Count';
            app.Plot2IndexDropDown = uidropdown(app.GridLayout);
            app.Plot2IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Plot2IndexDropDown.Value = 'Image_1';
            app.Plot1StyleDropDown = uidropdown(app.GridLayout);
            app.Plot1StyleDropDown.Items = {'Mean Count', 'Max Count', 'GaussXCenter', 'Filling'};
            app.Plot1StyleDropDown.Value = 'Mean Count';
            app.Plot1IndexDropDown = uidropdown(app.GridLayout);
            app.Plot1IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Plot1IndexDropDown.Value = 'Image_1';
            app.Axes2StyleDropDown = uidropdown(app.GridLayout);
            app.Axes2StyleDropDown.Items = {'Raw', 'Background-subtracted', 'Background offset'};
            app.Axes2StyleDropDown.Value = 'Background-subtracted';
            app.Axes2IndexDropDown = uidropdown(app.GridLayout);
            app.Axes2IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Axes2IndexDropDown.Value = 'Image_2';
            app.Axes1StyleDropDown = uidropdown(app.GridLayout);
            app.Axes1StyleDropDown.Items = {'Raw', 'Background-subtracted', 'Background offset'};
            app.Axes1StyleDropDown.Value = 'Background-subtracted';
            app.Axes1IndexDropDown = uidropdown(app.GridLayout);
            app.Axes1IndexDropDown.Items = {'Image_1', 'Image_2'};
            app.Axes1IndexDropDown.Value = 'Image_1';
            app.RunAutoSaveButton = uibutton(app.GridLayout, 'push');
            app.RunAutoSaveButton.Text = 'Run & AutoSave';
            app.StopButton = uibutton(app.GridLayout, 'state');
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.Text = 'Save';
            app.RunButton = uibutton(app.GridLayout, 'push');
            app.RunButton.Text = 'Run';
            app.InitAcquisitionButton = uibutton(app.GridLayout, 'push');
            app.InitAcquisitionButton.Text = 'Init Acquisition';
            app.ShowROIButton = uibutton(app.GridLayout, 'push');
            app.ShowROIButton.Text = 'Show ROI';
            app.ROIradiusEditFieldLabel = uilabel(app.GridLayout);
            app.ROIradiusEditFieldLabel.Text = 'ROI radius';
            app.ROIradiusEditField = uieditfield(app.GridLayout, 'numeric');
            app.ROIradiusEditField.Value = 1;
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.AcquisitionPanel = uipanel(app.SettingTab);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.EstimatedDatasetsizeLabel = uilabel(app.AcquisitionPanel);
            app.EstimatedDatasetsizeLabel.Text = 'Estimated Dataset size: ';
            app.NumberofacquisitionsSpinnerLabel = uilabel(app.AcquisitionPanel);
            app.NumberofacquisitionsSpinnerLabel.Text = 'Number of acquisitions';
            app.NumberofacquisitionsSpinner = uispinner(app.AcquisitionPanel);
            app.NumberofacquisitionsSpinner.ValueDisplayFormat = '%d';
            app.NumberofacquisitionsSpinner.Value = 20;
            app.AcquisitiontimeoutsSpinnerLabel = uilabel(app.AcquisitionPanel);
            app.AcquisitiontimeoutsSpinnerLabel.Text = 'Acquisition time out (s)';
            app.AcquisitiontimeoutsSpinner = uispinner(app.AcquisitionPanel);
            app.AcquisitiontimeoutsSpinner.Value = 1000;
            app.InitializeAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.InitializeAcquisitionButton.Text = 'Initialize Acquisition';
            app.UITable = uitable(app.AcquisitionPanel);
            app.UITable.ColumnName = {'Order'; 'Camera'; 'Label'; 'Note'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false];
            app.UITable.ColumnEditable = [false true true true];
            app.PoolingintervalsLabel = uilabel(app.AcquisitionPanel);
            app.PoolingintervalsLabel.Text = 'Refresh interval (s)';
            app.RefreshIntervalSpinner = uispinner(app.AcquisitionPanel);
            app.RefreshIntervalSpinner.Value = 0.01;
            app.AnalysisPanel = uipanel(app.SettingTab);
            app.AnalysisPanel.Title = 'Analysis';
            app.LatticeCalibrationDropDownLabel = uilabel(app.AnalysisPanel);
            app.LatticeCalibrationDropDownLabel.Text = 'Lattice Calibration';
            app.LatticeCalibrationDropDown = uidropdown(app.AnalysisPanel);
            app.LatticeCalibrationDropDown.Items = {''};
            app.LatticeCalibrationDropDown.Value = '';
            app.BackgroundOffsetDropDownLabel = uilabel(app.AnalysisPanel);
            app.BackgroundOffsetDropDownLabel.Text = 'Background Offset';
            app.BackgroundOffsetDropDown = uidropdown(app.AnalysisPanel);
            app.BackgroundOffsetDropDown.Items = {'StatBackground_20240327_HSSpeed=2_VSSpeed=1'};
            app.BackgroundOffsetDropDown.Value = 'StatBackground_20240327_HSSpeed=2_VSSpeed=1';
            app.DMDPatternScriptDropDownLabel = uilabel(app.AnalysisPanel);
            app.DMDPatternScriptDropDownLabel.Text = 'DMD Pattern Script';
            app.DMDPatternScriptDropDown = uidropdown(app.AnalysisPanel);
            app.DMDPatternScriptDropDown.Items = {''};
            app.DMDPatternScriptDropDown.Value = '';
            app.CameraControlPanel = uipanel(app.SettingTab);
            app.CameraControlPanel.Title = 'Camera Control';
            app.Andor19330Panel = uipanel(app.CameraControlPanel);
            app.Andor19330Panel.Title = 'Andor 19330';
            app.ExposuresSpinnerLabel = uilabel(app.Andor19330Panel);
            app.ExposuresSpinnerLabel.Text = 'Exposure (s)';
            app.Andor19330ExposuresSpinner = uispinner(app.Andor19330Panel);
            app.Andor19330ExposuresSpinner.Step = 0.1;
            app.Andor19330ExposuresSpinner.Limits = [0 Inf];
            app.Andor19330ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.AcquisitionmodeDropDownLabel.Text = 'Acquisition mode';
            app.Andor19330AcquisitionmodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped Frame 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19330AcquisitionmodeDropDown.Value = 'Full Frame 1';
            app.TriggermodeDropDownLabel = uilabel(app.Andor19330Panel);
            app.TriggermodeDropDownLabel.Text = 'Trigger mode';
            app.Andor19330TriggermodeDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19330TriggermodeDropDown.Value = 'External';
            app.ConfigureAndor19330Button = uibutton(app.Andor19330Panel, 'push');
            app.ConfigureAndor19330Button.Text = 'Configure Andor 19330';
            app.CloseAndor19330Button = uibutton(app.Andor19330Panel, 'push');
            app.CloseAndor19330Button.Text = 'Close Andor 19330';
            app.Andor19330StatusLamp = uilamp(app.Andor19330Panel);
            app.ReadoutspeedLabel = uilabel(app.Andor19330Panel);
            app.ReadoutspeedLabel.Text = 'Readout speed';
            app.Andor19330ReadoutSpeedDropDown = uidropdown(app.Andor19330Panel);
            app.Andor19330ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19330ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19330EstimatedreadouttimeLabel = uilabel(app.Andor19330Panel);
            app.Andor19330EstimatedreadouttimeLabel.Text = 'Estimated readout/cycle time: ';
            app.CheckAndor19330TemperatureButton = uibutton(app.Andor19330Panel, 'push');
            app.CheckAndor19330TemperatureButton.Text = 'Check Temperature';
            app.Andor19330TemperatureLabel = uilabel(app.Andor19330Panel);
            app.Andor19330TemperatureLabel.Text = '';
            app.Andor19331Panel = uipanel(app.CameraControlPanel);
            app.Andor19331Panel.Title = 'Andor 19331';
            app.ExposuresSpinner_2Label = uilabel(app.Andor19331Panel);
            app.ExposuresSpinner_2Label.Text = 'Exposure (s)';
            app.Andor19331ExposuresSpinner = uispinner(app.Andor19331Panel);
            app.Andor19331ExposuresSpinner.Step = 0.1;
            app.Andor19331ExposuresSpinner.Value = 0.2;
            app.AcquisitionmodeDropDown_2Label = uilabel(app.Andor19331Panel);
            app.AcquisitionmodeDropDown_2Label.Text = 'Acquisition mode';
            app.Andor19331AcquisitionmodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331AcquisitionmodeDropDown.Items = {'Full Frame 1', 'Cropped Frame 1', 'FK 2', 'FK 4', 'FK 8'};
            app.Andor19331AcquisitionmodeDropDown.Value = 'Full Frame 1';
            app.TriggermodeDropDown_2Label = uilabel(app.Andor19331Panel);
            app.TriggermodeDropDown_2Label.Text = 'Trigger mode';
            app.Andor19331TriggermodeDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331TriggermodeDropDown.Items = {'External', 'Internal'};
            app.Andor19331TriggermodeDropDown.Value = 'External';
            app.ConfigureAndor19331Button = uibutton(app.Andor19331Panel, 'push');
            app.ConfigureAndor19331Button.Text = 'Configure Andor 19331';
            app.CloseAndor19331Button = uibutton(app.Andor19331Panel, 'push');
            app.CloseAndor19331Button.Text = 'Close Andor 19331';
            app.Andor19331StatusLamp = uilamp(app.Andor19331Panel);
            app.ReadoutspeedLabel_2 = uilabel(app.Andor19331Panel);
            app.ReadoutspeedLabel_2.Text = 'Readout speed';
            app.Andor19331ReadoutSpeedDropDown = uidropdown(app.Andor19331Panel);
            app.Andor19331ReadoutSpeedDropDown.Items = {'1MHz', '3MHz', '5MHz'};
            app.Andor19331ReadoutSpeedDropDown.Value = '1MHz';
            app.Andor19331EstimatedreadouttimeLabel = uilabel(app.Andor19331Panel);
            app.Andor19331EstimatedreadouttimeLabel.Text = 'Estimated readout/cycle time: ';
            app.CheckAndor19331TemperatureButton = uibutton(app.Andor19331Panel, 'push');
            app.CheckAndor19331TemperatureButton.Text = 'Check Temperature';
            app.Andor19331TemperatureLabel = uilabel(app.Andor19331Panel);
            app.Andor19331TemperatureLabel.Text = '';
            app.ZeluxPanel = uipanel(app.CameraControlPanel);
            app.ZeluxPanel.Title = 'Zelux';
            app.ExposuresSpinner_3Label = uilabel(app.ZeluxPanel);
            app.ExposuresSpinner_3Label.Text = 'Exposure (s)';
            app.ZeluxExposuresSpinner = uispinner(app.ZeluxPanel);
            app.ZeluxExposuresSpinner.Step = 0.1;
            app.ZeluxExposuresSpinner.Value = 1e-05;
            app.TriggermodeDropDown_3Label = uilabel(app.ZeluxPanel);
            app.TriggermodeDropDown_3Label.Text = 'Trigger mode';
            app.ZeluxTriggermodeDropDown = uidropdown(app.ZeluxPanel);
            app.ZeluxTriggermodeDropDown.Items = {'External', 'Internal'};
            app.ZeluxTriggermodeDropDown.Value = 'External';
            app.ConfigureZeluxButton = uibutton(app.ZeluxPanel, 'push');
            app.ConfigureZeluxButton.Text = 'Configure Zelux';
            app.CloseZeluxButton = uibutton(app.ZeluxPanel, 'push');
            app.CloseZeluxButton.Text = 'Close Zelux';
            app.ZeluxStatusLamp = uilamp(app.ZeluxPanel);
            app.ZeluxEstimatedreadouttimeLabel = uilabel(app.ZeluxPanel);
            app.ZeluxEstimatedreadouttimeLabel.Text = 'Estimated readout time: ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ElapsedTimeLabel = uilabel(app.UIFigure);
            app.ElapsedTimeLabel.Text = 'Elapsed Time:';
            app.RunNumLabel = uilabel(app.UIFigure);
            app.RunNumLabel.Text = 'Run Num: ';
            app.TotalRunTimeLabel = uilabel(app.UIFigure);
            app.TotalRunTimeLabel.Text = 'Total Run Time:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ExportSettingstoFileMenu = uimenu(app.ContextMenu);
            app.ExportSettingstoFileMenu.Text = 'Export Settings to File';
            app.LoadSettingsfromFileMenu = uimenu(app.ContextMenu);
            app.LoadSettingsfromFileMenu.Text = 'Load Settings from File';
            app.SetCurrentSettingsasDefaultMenu = uimenu(app.ContextMenu);
            app.SetCurrentSettingsasDefaultMenu.Text = 'Set Current Settings as Default';
            % Assign app.ContextMenu
            app.SettingTab.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageAcquisitorNew

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startApp)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end